classdef Receiver_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ReceiverLabel                   matlab.ui.control.Label
        ModulatedSignalFileNameEditFieldLabel  matlab.ui.control.Label
        ModulatedSignalFileNameEditField  matlab.ui.control.EditField
        ImportandDemodulateButton       matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        HomeButton                      matlab.ui.control.Button
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.EditField
        PhaseDeviationEditFieldLabel    matlab.ui.control.Label
        PhaseDeviationEditField         matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModulatedSignalFileNameEditField
        function ModulatedSignalFileNameEditFieldValueChanged(app, event)
            value = app.ModulatedSignalFileNameEditField.Value;
            
        end

        % Button pushed function: ImportandDemodulateButton
        function ImportandDemodulateButtonPushed(app, event)
           [filename,filepath] = uigetfile();
           app.ModulatedSignalFileNameEditField.Value=filename;
           fullpath=fullfile(filename,filepath)
           [audioData] = audioread(filename);
           Fc=str2double(app.CarrierFrequencyEditField.Value);
           Fdev=str2double(app.PhaseDeviationEditField.Value)
           demodulatedSignal = fmdemod(audioData,Fc,8000,Fdev);
           plot(app.UIAxes,demodulatedSignal);
           xlabel(app.UIAxes,'Time');
           ylabel(app.UIAxes,'Amplitude');
           sound(demodulatedSignal*50,8000);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            app.ModulatedSignalFileNameEditField.Value=' ';
           
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            Home
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Demodulated Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ReceiverLabel = uilabel(app.UIFigure);
            app.ReceiverLabel.Text = 'Receiver';
            app.ModulatedSignalFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModulatedSignalFileNameEditFieldLabel.Text = 'Modulated Signal File Name';
            app.ModulatedSignalFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.ModulatedSignalFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @ModulatedSignalFileNameEditFieldValueChanged, true);
            app.ImportandDemodulateButton = uibutton(app.UIFigure, 'push');
            app.ImportandDemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @ImportandDemodulateButtonPushed, true);
            app.ImportandDemodulateButton.Text = 'Import and Demodulate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.UIFigure, 'text');
            app.PhaseDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseDeviationEditFieldLabel.Text = 'Phase Deviation';
            app.PhaseDeviationEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end