classdef transmitter_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RecordButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        DurationTextAreaLabel          matlab.ui.control.Label
        DurationTextArea               matlab.ui.control.TextArea
        CarrierFrequencymustbeLowerthan4000HzLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        CarrierFrequencyTextAreaLabel  matlab.ui.control.Label
        CarrierFrequencyTextArea       matlab.ui.control.TextArea
        PhaseDeviationTextAreaLabel    matlab.ui.control.Label
        PhaseDeviationTextArea         matlab.ui.control.TextArea
        TrasmitterLabel                matlab.ui.control.Label
        HomeButton                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            audioObject=audiorecorder;
            duration= str2double(app.DurationTextArea.Value{1});
            msgbox('Recording Started');
            recordblocking(audioObject,duration);
            msgbox('Recording Stopped');
            y=getaudiodata(audioObject);
            Fs=audioObject.SampleRate;
            plot(app.UIAxes,y);
            xlabel(app.UIAxes,'Time');
            ylabel(app.UIAxes,'Amplitude');
            fDev=str2double(app.PhaseDeviationTextArea.Value);
            Fc=str2double(app.CarrierFrequencyTextArea.Value);
            z = fmmod(y,Fc,Fs,fDev);
            plot(app.UIAxes2,z);
            xlabel(app.UIAxes2,'Time');
            ylabel(app.UIAxes2,'Amplitude');
            filename = 'Handel.wav';
            audiowrite(filename,z,Fs);
            msg = sprintf('Audio file exported as %s.', filename);
            uialert(app.UIFigure, msg, 'Success');
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            Home
            closereq(); 
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
           app.DurationTextArea.Value=' ';
           app.CarrierFrequencyTextArea.Value=' ';
           app.PhaseDeviationTextArea.Value=' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voice SIgnal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DurationTextAreaLabel = uilabel(app.UIFigure);
            app.DurationTextAreaLabel.Text = 'Duration';
            app.DurationTextArea = uitextarea(app.UIFigure);
            app.CarrierFrequencymustbeLowerthan4000HzLabel = uilabel(app.UIFigure);
            app.CarrierFrequencymustbeLowerthan4000HzLabel.Text = 'Carrier Frequency must be Lower than 4000 Hz';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Fm Modulated SIgnal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.CarrierFrequencyTextAreaLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyTextAreaLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyTextArea = uitextarea(app.UIFigure);
            app.PhaseDeviationTextAreaLabel = uilabel(app.UIFigure);
            app.PhaseDeviationTextAreaLabel.Text = 'Phase Deviation';
            app.PhaseDeviationTextArea = uitextarea(app.UIFigure);
            app.TrasmitterLabel = uilabel(app.UIFigure);
            app.TrasmitterLabel.Text = 'Trasmitter';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmitter_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end